classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SelectDatasetButton            matlab.ui.control.Button
        VisualizationtypeButtonGroup   matlab.ui.container.ButtonGroup
        TimelapseButton                matlab.ui.control.RadioButton
        DifferenceImageButton          matlab.ui.control.RadioButton
        SliderButton                   matlab.ui.control.RadioButton
        SegmentationButton             matlab.ui.control.RadioButton
        CurrentpathLabel               matlab.ui.control.Label
        CurrentpathTextArea            matlab.ui.control.TextArea
        LoadandPrepareDatasetButton    matlab.ui.control.Button
        ModesofChangePanel             matlab.ui.container.Panel
        SizeofChangeButtonGroup        matlab.ui.container.ButtonGroup
        ShortTermButton                matlab.ui.control.ToggleButton
        LongTermButton                 matlab.ui.control.ToggleButton
        ImageQualityButtonGroup        matlab.ui.container.ButtonGroup
        FasterKAZEButton               matlab.ui.control.ToggleButton
        MoreRobustSURFButton           matlab.ui.control.ToggleButton
        ImageContentButtonGroup        matlab.ui.container.ButtonGroup
        LandscapeButton                matlab.ui.control.ToggleButton
        UrbanButton                    matlab.ui.control.ToggleButton
        ImagetouseasreferenceDropDownLabel  matlab.ui.control.Label
        ImagetouseasreferenceDropDown  matlab.ui.control.DropDown
        ImagetouseasreferenceDropDown_2Label  matlab.ui.control.Label
        ImagetouseasreferenceDropDown_2  matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        AnalyzeButton                  matlab.ui.control.Button
        StatusField                    matlab.ui.control.EditField
        Panel                          matlab.ui.container.Panel
        FPSLabel                       matlab.ui.control.Label
        FPSField                       matlab.ui.control.NumericEditField
        EndPlaybackButton              matlab.ui.control.StateButton
        PositionSlider                 matlab.ui.control.Slider
        ImageAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        target_folder % source folder for images
        viz_type % which type of visualization to produce
        register_ok
        analyze_ok
        image_array
        name_array
        
        %mode of change flags
        surf_flag
        large_flag
        
        % Slider update handle
        slider_handle
    end
    
    properties (Access = public)
        
        EndPlayback
        %Mode of change
    end
    
    methods (Access = private)
        function Mousemouv(app)
            app.UIFigure.WindowButtonMotionFcn = @MoveCallback;
            function MoveCallback(srs,~)
                x_pos = srs.CurrentPoint;
                A = app.SelectDatasetButton.Position;
                B = app.LoadandPrepareDatasetButton.Position;
                C = app.AnalyzeButton.Position;
                if  (x_pos(1) >A(1) ) && (x_pos(1) < A(1)+A(3)) ...
                        && (x_pos(2) > A(2)) && (x_pos(2) <A(2)+A(4))
                    % If so change the pointer
                    set(app.UIFigure,'Pointer','hand')
                elseif (x_pos(1) >B(1) ) && (x_pos(1) < B(1)+B(3)) ...
                        && (x_pos(2) > B(2)) && (x_pos(2) <B(2)+B(4))
                    % If so change the pointer
                    set(app.UIFigure,'Pointer','hand');
                elseif (x_pos(1) >C(1) ) && (x_pos(1) < C(1)+C(3)) ...
                        && (x_pos(2) > C(2)) && (x_pos(2) <C(2)+C(4))
                    % If so change the pointer
                    set(app.UIFigure,'Pointer','hand');
                else
                    % Otherwise set/keep the default arrow pointer
                    set(app.UIFigure,'Pointer','arrow');
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            app.viz_type='Timelapse';
            
            app.target_folder=[];
            app.CurrentpathTextArea.Value='Please select a dataset';
            
            app.AnalyzeButton.BackgroundColor=[1.00,0.00,0.00];
            app.LoadandPrepareDatasetButton.BackgroundColor=[1.00,0.00,0.00];
            app.SelectDatasetButton.BackgroundColor=[0,0.60,0.00];
            
            app.analyze_ok=false;
            app.register_ok=false;
            
            app.StatusField.Value='Load Dataset';
            
            app.FPSField.Value=1;
            
            app.PositionSlider.Value=0.5;
            app.PositionSlider.Visible=false;
            
            app.large_flag=0;
            app.surf_flag=0;
            
            app.ImagetouseasreferenceDropDown.Items={'First Load and Prepare Dataset'};
            app.ImagetouseasreferenceDropDown.Value='First Load and Prepare Dataset';
            Mousemouv(app);
            
            app.ImagetouseasreferenceDropDown_2.Items={'First Load and Prepare Dataset'};
            app.ImagetouseasreferenceDropDown_2.Value='First Load and Prepare Dataset';
            app.ImagetouseasreferenceDropDown_2.Visible=false;
            app.ImagetouseasreferenceDropDown_2Label.Visible=false;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {666, 666};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CurrentpathTextArea, ImageAxes, 
        % SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            tmp= uigetdir('Select a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if tmp==0
                return
            end
            app.target_folder=tmp;
            imageFiles=dir([app.target_folder '/*.jpg']);
            
            if size(imageFiles,1)==0
                errorMessage = sprintf('Error: The target folder does not contain any images. (.jpg format). Please choose another!');
                uialert(app.UIFigure,errorMessage,'Invalid File');
                return;
            end
            
            app.CurrentpathTextArea.Value=app.target_folder;
            
            app.register_ok=true;
            app.analyze_ok=false;
            app.AnalyzeButton.BackgroundColor=[1,0.00,0.00];
            app.LoadandPrepareDatasetButton.BackgroundColor=[0,0.60,0.00];
            app.StatusField.Value='Load and prepare the dataset';
            
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
            if app.analyze_ok
                %set index of reference image
                j=app.ImagetouseasreferenceDropDown.Value;
                t=app.ImagetouseasreferenceDropDown_2.Value;
                %set index of image to compare
                if app.large_flag
                    j=1;
                    t=1;
                    k=size(app.image_array,2);
                else
                    k=j+1;
                end
                %update status field
                app.StatusField.Value='Processing...';
                
                switch app.viz_type
                
                    case 'Timelapse'
                        %create a timelapse visualization
                        app.AnalyzeButton.BackgroundColor=[1,0.00,0.00];
                        app.analyze_ok=false;
                        app.StatusField.Value='Playing timelapse. End playback to continue';
                        
                        timelapse(app, app.image_array);
                        
                        app.AnalyzeButton.BackgroundColor=[0,0.60,0.00];
                        app.analyze_ok=true;
                        app.StatusField.Value='Ready!';
                    case 'Difference Image'
                        %create difference image visualization
                        
                        [~,ssimmap]=ssim(app.image_array{j},app.image_array{k});
                        imagesc(app.ImageAxes,ssimmap);
                        
                    case 'Slider'
                        %                             app.register_ok=false;
                        app.slider_handle=slider_func(app.ImageAxes, app.image_array{k}, app.image_array{j},app.PositionSlider.Value);
                        
                    case 'Segmentation'
                        app.StatusField.Value='Performing segmentation...';
                        selectedButton = app.ImageContentButtonGroup.SelectedObject;
                        if strcmp(selectedButton.Text,'Landscape')
                            content = 0;
                        else
                            content = 1;
                        end                           
                        segmentation(app.image_array{t}, content, app.ImageAxes);
                end
                app.StatusField.Value='Done!';
                
            end


        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
            
            %show timelapse playback controls only if the option is
            %selected
            if strcmp(app.viz_type,'Timelapse')
                %                 app.Panel.Visible=true;
                app.EndPlaybackButton.Visible=true;
                app.FPSField.Visible=true;
                app.FPSLabel.Visible=true;
            else
                %                 app.Panel.Visible=false;
                app.EndPlaybackButton.Visible=false;
                app.FPSField.Visible=false;
                app.FPSLabel.Visible=false;
            end
            
            if strcmp(app.viz_type,'Slider')
                app.PositionSlider.Visible=true;
            else
                app.PositionSlider.Visible=false;
            end
            
            if strcmp(app.viz_type,'Segmentation')
                app.ImagetouseasreferenceDropDown.Visible=false;
                app.ImagetouseasreferenceDropDownLabel.Visible=false;
                app.ImagetouseasreferenceDropDown_2.Visible=true;
                app.ImagetouseasreferenceDropDown_2Label.Visible=true;
            else
                app.ImagetouseasreferenceDropDown.Visible=true;
                app.ImagetouseasreferenceDropDownLabel.Visible=true;
                app.ImagetouseasreferenceDropDown_2.Visible=false;
                app.ImagetouseasreferenceDropDown_2Label.Visible=false;
            end            
        end

        % Callback function
        function ModeofChangeDropDownValueChanged(app, event)
            value = app.ModeofChangeDropDown.Value;
            app.mode_change = value;
        end

        % Value changed function: EndPlaybackButton
        function PlayPauseButtonValueChanged(app, event)
            app.EndPlayback = app.EndPlaybackButton.Value;
            app.AnalyzeButton.BackgroundColor=[0,0.60,0.00];
            
            app.register_ok=true;
            
            
        end

        % Selection changed function: ImageQualityButtonGroup
        function ImageQualityButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageQualityButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Faster (KAZE)')
                app.surf_flag=0;
                
            elseif strcmp(selectedButton.Text,'More Robust (SURF)')
                app.surf_flag=1;
                
            end
        end

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            
            slider_update(app.slider_handle,changingValue);
            
            
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            
            
        end

        % Selection changed function: SizeofChangeButtonGroup
        function SizeofChangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SizeofChangeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Long Term')
                app.large_flag=1;
                app.ImagetouseasreferenceDropDown.Visible=false;
                app.ImagetouseasreferenceDropDownLabel.Visible=false;
                app.ImagetouseasreferenceDropDown_2.Visible=false;
                app.ImagetouseasreferenceDropDown_2Label.Visible=false;
            elseif strcmp(selectedButton.Text,'Short Term')
                if strcmp(app.viz_type,'Segmentation')
                 app.ImagetouseasreferenceDropDown_2.Visible=true;
                 app.ImagetouseasreferenceDropDown_2Label.Visible=true;
                 app.ImagetouseasreferenceDropDown.Visible=false;
                 app.ImagetouseasreferenceDropDownLabel.Visible=false;                    
                else
                 app.ImagetouseasreferenceDropDown_2.Visible=false;
                 app.ImagetouseasreferenceDropDown_2Label.Visible=false;
                 app.ImagetouseasreferenceDropDown.Visible=true;
                 app.ImagetouseasreferenceDropDownLabel.Visible=true;
                end

                app.large_flag=0;
            end
        end

        % Button pushed function: LoadandPrepareDatasetButton
        function LoadandPrepareDatasetButtonPushed(app, event)
            if app.register_ok
                app.StatusField.Value='Registering and preparing images';
                d = uiprogressdlg(app.UIFigure,'Title','Registering and preparing images',...
                    'Indeterminate','on');
                drawnow
                [app.image_array, app.name_array]=loadData(app.target_folder,app.surf_flag, app.UIFigure);
                close(d);
                set(app.UIFigure,'Pointer','arrow');
                app.AnalyzeButton.BackgroundColor=[0,0.60,0.00];
                app.analyze_ok=true;
                app.StatusField.Value='Ready!';
            end
            
            
                app.ImagetouseasreferenceDropDown_2.Items=app.name_array(1:end);
                app.ImagetouseasreferenceDropDown_2.ItemsData=1:size(app.name_array,2);
            
                app.ImagetouseasreferenceDropDown.Items=app.name_array(1:end-1);
                app.ImagetouseasreferenceDropDown.ItemsData=1:size(app.name_array,2)-1;
            
            %app.ImagetouseasreferenceDropDown.Items=app.name_array(1:end-1);
            %app.ImagetouseasreferenceDropDown.ItemsData=1:size(app.name_array,2)-1;
            %             app.ImagetouseasreferenceDropDown.Value=app.ImagetouseasreferenceDropDown.ItemsData[1];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectDatasetButton = uibutton(app.LeftPanel, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.TimelapseButton.Value = true;
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.SegmentationButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SegmentationButton.Text = 'Segmentation';
            app.CurrentpathLabel = uilabel(app.LeftPanel);
            app.CurrentpathLabel.Text = 'Current path:';
            app.CurrentpathTextArea = uitextarea(app.LeftPanel);
            app.CurrentpathTextArea.ValueChangedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.CurrentpathTextArea.Editable = 'off';
            app.LoadandPrepareDatasetButton = uibutton(app.LeftPanel, 'push');
            app.LoadandPrepareDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandPrepareDatasetButtonPushed, true);
            app.LoadandPrepareDatasetButton.Text = 'Load and Prepare Dataset';
            app.ModesofChangePanel = uipanel(app.LeftPanel);
            app.ModesofChangePanel.Title = 'Modes of Change';
            app.SizeofChangeButtonGroup = uibuttongroup(app.ModesofChangePanel);
            app.SizeofChangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SizeofChangeButtonGroupSelectionChanged, true);
            app.SizeofChangeButtonGroup.Title = 'Size of Change';
            app.ShortTermButton = uitogglebutton(app.SizeofChangeButtonGroup);
            app.ShortTermButton.Text = 'Short Term';
            app.ShortTermButton.Value = true;
            app.LongTermButton = uitogglebutton(app.SizeofChangeButtonGroup);
            app.LongTermButton.Text = 'Long Term';
            app.ImageQualityButtonGroup = uibuttongroup(app.ModesofChangePanel);
            app.ImageQualityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageQualityButtonGroupSelectionChanged, true);
            app.ImageQualityButtonGroup.Title = 'Image Quality';
            app.FasterKAZEButton = uitogglebutton(app.ImageQualityButtonGroup);
            app.FasterKAZEButton.Text = 'Faster (KAZE)';
            app.FasterKAZEButton.Value = true;
            app.MoreRobustSURFButton = uitogglebutton(app.ImageQualityButtonGroup);
            app.MoreRobustSURFButton.Text = 'More Robust (SURF)';
            app.ImageContentButtonGroup = uibuttongroup(app.ModesofChangePanel);
            app.ImageContentButtonGroup.Title = 'Image Content';
            app.LandscapeButton = uitogglebutton(app.ImageContentButtonGroup);
            app.LandscapeButton.Text = 'Landscape';
            app.LandscapeButton.Value = true;
            app.UrbanButton = uitogglebutton(app.ImageContentButtonGroup);
            app.UrbanButton.Text = 'Urban';
            app.ImagetouseasreferenceDropDownLabel = uilabel(app.ModesofChangePanel);
            app.ImagetouseasreferenceDropDownLabel.Text = 'Image to use as reference';
            app.ImagetouseasreferenceDropDown = uidropdown(app.ModesofChangePanel);
            app.ImagetouseasreferenceDropDown_2Label = uilabel(app.ModesofChangePanel);
            app.ImagetouseasreferenceDropDown_2Label.Text = 'Image to use as reference';
            app.ImagetouseasreferenceDropDown_2 = uidropdown(app.ModesofChangePanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalyzeButton = uibutton(app.RightPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StatusField = uieditfield(app.RightPanel, 'text');
            app.StatusField.Value = 'Playing timelapse. End playback to continue';
            app.Panel = uipanel(app.RightPanel);
            app.FPSLabel = uilabel(app.Panel);
            app.FPSLabel.Text = 'FPS:';
            app.FPSField = uieditfield(app.Panel, 'numeric');
            app.FPSField.Limits = [1 20];
            app.FPSField.Value = 1;
            app.EndPlaybackButton = uibutton(app.Panel, 'state');
            app.EndPlaybackButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.EndPlaybackButton.Text = 'End Playback';
            app.PositionSlider = uislider(app.Panel);
            app.PositionSlider.Limits = [0.001 0.999];
            app.PositionSlider.MajorTicks = [];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.PositionSlider.MinorTicks = [];
            app.PositionSlider.Value = 0.5;
            app.ImageAxes = uiaxes(app.RightPanel);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end