classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectDatasetButton           matlab.ui.control.Button
        VisualizationtypeButtonGroup  matlab.ui.container.ButtonGroup
        TimelapseButton               matlab.ui.control.RadioButton
        DifferenceImageButton         matlab.ui.control.RadioButton
        SliderButton                  matlab.ui.control.RadioButton
        ColordistributionButton       matlab.ui.control.RadioButton
        AnalyzeButton                 matlab.ui.control.Button
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        viz_type % which type of visualization to produce
    end
    
    methods (Access = private)
        
        function update_image(app,target_folder)
            
            imageFiles=dir([target_folder '/*.jpg']);
            imageFileName = imageFiles(1).name;
            
            
            fullFileName = fullfile(target_folder, imageFileName);
            
            try
                im = imread(fullFileName);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            imagesc(app.ImageAxes,im);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            
        end

        % Callback function: ImageAxes, SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            target_folder = uigetdir('Load a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if ~isfolder(target_folder)
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            update_image(app,target_folder);
            
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
        switch app.viz_type
            case 'Timelapse'
                %create a timelapse visualization
                app.VisualizationtypeButtonGroup.BackgroundColor=[0.00,1.00,0.00];
            case 'Difference Image'
                %create difference image visualization
                app.VisualizationtypeButtonGroup.BackgroundColor=[1.00,0.00,0.00];
            case 'Slider'
            
            case 'Color distribution?'
        end
            
        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectDatasetButton = uibutton(app.UIFigure, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.TimelapseButton.Value = true;
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.ColordistributionButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.ColordistributionButton.Text = 'Color distribution?';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end