classdef UI_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectDatasetButton           matlab.ui.control.Button
        VisualizationtypeButtonGroup  matlab.ui.container.ButtonGroup
        TimelapseButton               matlab.ui.control.RadioButton
        DifferenceImageButton         matlab.ui.control.RadioButton
        SliderButton                  matlab.ui.control.RadioButton
        CurrentpathLabel              matlab.ui.control.Label
        CurrentpathTextArea           matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        AlignmentMethodTab            matlab.ui.container.Tab
        ButtonGroup                   matlab.ui.container.ButtonGroup
        FasterKAZEButton              matlab.ui.control.ToggleButton
        MoreRobustSURFButton          matlab.ui.control.ToggleButton
        SizeofChangeTab               matlab.ui.container.Tab
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SmallScaleButton_2            matlab.ui.control.ToggleButton
        LargeScaleButton              matlab.ui.control.ToggleButton
        RightPanel                    matlab.ui.container.Panel
        AnalyzeButton                 matlab.ui.control.Button
        StatusField                   matlab.ui.control.EditField
        Panel                         matlab.ui.container.Panel
        FPSLabel                      matlab.ui.control.Label
        FPSField                      matlab.ui.control.NumericEditField
        EndPlaybackButton             matlab.ui.control.StateButton
        PositionSlider                matlab.ui.control.Slider
        ImageAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        viz_type % which type of visualization to produce
        analyze_ok
    end
    
    properties (Access = public)
        target_folder % source folder for images
        EndPlayback
        %Mode of change
        surf_flag
        large_flag
        % Slider
        SliderChanging
        slider_handle
        
        slider_image_left
        slider_image_right
        
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            app.viz_type='Timelapse';
            
            app.target_folder='.';
            app.CurrentpathTextArea.Value='Please select a dataset';
            
            app.AnalyzeButton.BackgroundColor=[1.00,0.00,0.00];
            app.analyze_ok=false;
            
            app.StatusField.Value='Load Dataset';
            
            app.FPSField.Value=1;
            
            
            app.SliderChanging=0.5;
            app.PositionSlider.Visible=false;
            
            app.large_flag=0;
            app.surf_flag=0;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {709, 709};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CurrentpathTextArea, ImageAxes, 
        % SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            app.target_folder = uigetdir('Load a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if app.target_folder==0
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            imageFiles=dir([app.target_folder '/*.jpg']);
            
            if size(imageFiles,1)==0
                errorMessage = sprintf('Error: The target folder does not contain any images. (must be in .jpg format). Please choose another!', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            app.CurrentpathTextArea.Value=app.target_folder;
            
            app.analyze_ok=true;
            app.AnalyzeButton.BackgroundColor=[0,1.00,0.00];
            app.StatusField.Value='Ready!';
            
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
            if app.analyze_ok
                
                %update status field
                app.StatusField.Value='Processing...';
                app.StatusField.Visible=true;
                drawnow
                
                %                 %Load images from source folder
                imageFiles=dir([app.target_folder '/*.jpg']); % all images
                %                 Nfile=size(imageFiles,1);                     % Number of images
                
                
                switch app.viz_type
                    case 'Timelapse'
                        %create a timelapse visualization
                        
                        timelapse(app);
                    case 'Difference Image'
                        %create difference image visualization
                        diff_image(app)
                        
                        
                    case 'Slider'
                        app.slider_image_left=imread(fullfile(app.target_folder,imageFiles(1).name));
                        app.slider_image_right=imread(fullfile(app.target_folder,imageFiles(end).name));
                        [tform, error_flag]=register(rgb2gray(app.slider_image_left),rgb2gray(app.slider_image_right),0);
                        if error_flag
                            uialert(fig,'Registration Failed, diff image could not be displayed','Registration Error');
                            return
                        else
                            app.slider_image_right = imwarp(app.slider_image_right,tform,'OutputView',imref2d(size(app.slider_image_left)));
                            
                            app.analyze_ok=false;
                            app.slider_handle=slider_func(app.ImageAxes, app.slider_image_right, app.slider_image_left,app.SliderChanging);
                        end
                    case 'Color distribution?'
                        
                end
                
                app.StatusField.Value='Done!';
                
            end
            
        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
            
            %show timelapse playback controls only if the option is
            %selected
            if strcmp(app.viz_type,'Timelapse')
                %                 app.Panel.Visible=true;
                app.EndPlaybackButton.Visible=true;
                app.FPSField.Visible=true;
                app.FPSLabel.Visible=true;
            else
                %                 app.Panel.Visible=false;
                app.EndPlaybackButton.Visible=false;
                app.FPSField.Visible=false;
                app.FPSLabel.Visible=false;
            end
            
            if strcmp(app.viz_type,'Slider')
                app.PositionSlider.Visible=true;
            else
                app.PositionSlider.Visible=false;
            end
        end

        % Callback function
        function ModeofChangeDropDownValueChanged(app, event)
            value = app.ModeofChangeDropDown.Value;
            app.mode_change = value;
        end

        % Value changed function: EndPlaybackButton
        function PlayPauseButtonValueChanged(app, event)
            app.EndPlayback = app.EndPlaybackButton.Value;
            app.AnalyzeButton.BackgroundColor=[0,1.00,0.00];
            
            app.analyze_ok=true;
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Faster (KAZE)')
                app.surf_flag=0;
                
            elseif strcmp(selectedButton.Text,'More Robust (SURF)')
                app.surf_flag=1;
                
            end
        end

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SliderChanging = changingValue;
            slider_update(app.slider_handle,changingValue);
            
            
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            value = app.PositionSlider.Value;
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if strcmp(selectedButton.Text,'Large Scale')
                app.large_flag=1;
                
            elseif strcmp(selectedButton.Text,'Small Scale')
                app.large_flag=0;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectDatasetButton = uibutton(app.LeftPanel, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.TimelapseButton.Value = true;
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.CurrentpathLabel = uilabel(app.LeftPanel);
            app.CurrentpathLabel.Text = 'Current path:';
            app.CurrentpathTextArea = uitextarea(app.LeftPanel);
            app.CurrentpathTextArea.ValueChangedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.CurrentpathTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.AlignmentMethodTab = uitab(app.TabGroup);
            app.AlignmentMethodTab.Title = 'Alignment Method';
            app.ButtonGroup = uibuttongroup(app.AlignmentMethodTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FasterKAZEButton = uitogglebutton(app.ButtonGroup);
            app.FasterKAZEButton.Text = 'Faster (KAZE)';
            app.FasterKAZEButton.Value = true;
            app.MoreRobustSURFButton = uitogglebutton(app.ButtonGroup);
            app.MoreRobustSURFButton.Text = 'More Robust (SURF)';
            app.SizeofChangeTab = uitab(app.TabGroup);
            app.SizeofChangeTab.Title = 'Size of Change';
            app.ButtonGroup_2 = uibuttongroup(app.SizeofChangeTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.SmallScaleButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.SmallScaleButton_2.Text = 'Small Scale';
            app.SmallScaleButton_2.Value = true;
            app.LargeScaleButton = uitogglebutton(app.ButtonGroup_2);
            app.LargeScaleButton.Text = 'Large Scale';
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalyzeButton = uibutton(app.RightPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StatusField = uieditfield(app.RightPanel, 'text');
            app.StatusField.Value = 'Playing timelapse. End playback to continue';
            app.Panel = uipanel(app.RightPanel);
            app.FPSLabel = uilabel(app.Panel);
            app.FPSLabel.Text = 'FPS:';
            app.FPSField = uieditfield(app.Panel, 'numeric');
            app.FPSField.Limits = [1 20];
            app.FPSField.Value = 1;
            app.EndPlaybackButton = uibutton(app.Panel, 'state');
            app.EndPlaybackButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.EndPlaybackButton.Text = 'End Playback';
            app.PositionSlider = uislider(app.Panel);
            app.PositionSlider.Limits = [0.001 0.999];
            app.PositionSlider.MajorTicks = [];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.PositionSlider.MinorTicks = [];
            app.PositionSlider.Value = 0.5;
            app.ImageAxes = uiaxes(app.RightPanel);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end